classdef GuiTesting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Panel                      matlab.ui.container.Panel
        Image                      matlab.ui.control.Image
        StatusLabel                matlab.ui.control.Label
        Balance10000Label          matlab.ui.control.Label
        BetButton                  matlab.ui.control.Button
        BetAmountEditField         matlab.ui.control.NumericEditField
        BetAmountLabel             matlab.ui.control.Label
        RobotismovingcautionLabel  matlab.ui.control.Label
        PlayAgainButton            matlab.ui.control.Button
        toggleButton               matlab.ui.control.StateButton
        HitButton                  matlab.ui.control.Button
        StandButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        currentBalance = 10000
    end
    % properties (Access = private)
    %     BlackjackGameEngine % Description
    %     playerHand    % Player's hand
    %     dealerHand    % Dealer's hand
    %     betAmount     % Current bet amount
    % end
    %
    %
    % methods (Access = private)
    %     % Function to update output in the GUI
    %     function updateOutput(app, message)
    %         app.OutputArea.Value = [app.OutputArea.Value; message];
    %     end
    %
    %     % Wait for player decision (hit/stand)
    %     function waitForPlayerDecision(app)
    %         app.PlayerChoseHit = false;
    %         app.PlayerChoseStand = false;
    %         app.HitButton.Enable = 'on';
    %         app.StandButton.Enable = 'on';
    %         while ~app.PlayerChoseHit && ~app.PlayerChoseStand
    %             pause(0.1);
    %         end
    %         app.HitButton.Enable = 'off';
    %         app.StandButton.Enable = 'off';
    %     end
    %
    %     % Callback for the Hit button
    %     function onHitButtonPressed(app, event)
    %         app.PlayerChoseHit = true;
    %     end
    %
    %     % Callback for the Stand button
    %     function onStandButtonPressed(app, event)
    %         app.PlayerChoseStand = true;
    %     end
    %
    %     % Wait for bet amount input
    %     function waitForBet(app)
    %         while app.betAmount <= 0
    %             pause(0.1);
    %         end
    %     end
    %     % Update bet amount when value changes
    %     function BetInputValueChanged(app, event)
    %         app.betAmount = app.BetInput.Value;  % Retrieve the bet amount
    %     end
    %
    % end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: HitButton
        function HitButtonPushedCallback(app, event)
            % app.PlayerChoseHit = true;
        end

        % Button pushed function: StandButton
        function StandButtonPushedCallback(app, event)
            % app.PlayerChoseStand = true;
        end

        % Callback function: not associated with a component
        function CurrentBetEditFieldValueChanged(app, event)
            % value = app.CurrentBetEditField.balance;
        end

        % Button pushed function: PlayAgainButton
        function PlayAgainButtonPushed(app, event)
            % app.playAgain = true;
        end

        % Value changed function: toggleButton
        function toggleButtonValueChanged2(app, event)
            value = app.toggleButton.Value;
            % Check the value of the toggle button
            if app.toggleButton.Value  % If the toggle button is activated
                app.RobotismovingcautionLabel.Text = 'Robot standing-by!';
            else  % If the toggle button is deactivated
                app.RobotismovingcautionLabel.Text = 'Robot is in motion, caution!';
            end
        end

        % Button pushed function: BetButton
        function BetButtonPushed(app, event)
            inputAmount = app.BetAmountEditField.Value;

            % Validate the input
            if isnan(inputAmount) || inputAmount < 1 || inputAmount > 10000
                app.StatusLabel.Text = 'Please enter a valid amount between 1 and', num2str(app.currentBalance), 'Invalid Input';
            elseif inputAmount > app.currentBalance
                % Check if the input amount is greater than current balance
                app.StatusLabel.Text =  'Insufficient funds.', 'Error';
            elseif app.currentBalance == 0
                % Check if the balance is already zero
                app.StatusLabel.Text = 'Insufficient funds. You cannot place more bets.', 'Error';
            else
                % Update the chosen amount label
                app.StatusLabel.Text = ['Chosen Amount: $', num2str(inputAmount)];

                % Subtract the chosen amount from the balance
                app.currentBalance = app.currentBalance - inputAmount;

                % Update the balance label
                app.Balance10000Label.Text = ['Current Balance: $', num2str(app.currentBalance)];
            end

        end

        % Value changed function: BetAmountEditField
        function BetAmountEditFieldValueChanged(app, event)
   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.StandButton = uibutton(app.Panel, 'push');
            app.StandButton.ButtonPushedFcn = createCallbackFcn(app, @StandButtonPushedCallback, true);
            app.StandButton.Text = 'Stand';
            app.HitButton = uibutton(app.Panel, 'push');
            app.HitButton.ButtonPushedFcn = createCallbackFcn(app, @HitButtonPushedCallback, true);
            app.HitButton.Text = 'Hit';
            app.toggleButton = uibutton(app.Panel, 'state');
            app.toggleButton.ValueChangedFcn = createCallbackFcn(app, @toggleButtonValueChanged2, true);
            app.toggleButton.Text = 'E-Stop (Toggle';
            app.PlayAgainButton = uibutton(app.Panel, 'push');
            app.PlayAgainButton.ButtonPushedFcn = createCallbackFcn(app, @PlayAgainButtonPushed, true);
            app.PlayAgainButton.Text = 'Play Again';
            app.RobotismovingcautionLabel = uilabel(app.Panel);
            app.RobotismovingcautionLabel.Text = 'Robot is moving, caution!';
            app.BetAmountLabel = uilabel(app.Panel);
            app.BetAmountLabel.Text = 'Bet Amount:';
            app.BetAmountEditField = uieditfield(app.Panel, 'numeric');
            app.BetAmountEditField.ValueChangedFcn = createCallbackFcn(app, @BetAmountEditFieldValueChanged, true);
            app.BetAmountEditField.Value = 1000;
            app.BetButton = uibutton(app.Panel, 'push');
            app.BetButton.ButtonPushedFcn = createCallbackFcn(app, @BetButtonPushed, true);
            app.BetButton.Text = 'Bet';
            app.Balance10000Label = uilabel(app.Panel);
            app.Balance10000Label.Text = 'Balance: $10000';
            app.StatusLabel = uilabel(app.Panel);
            app.StatusLabel.Text = '';
            app.Image = uiimage(app.Panel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GuiTesting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end