classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ResetButton               matlab.ui.control.Button
        CurrentBetEditField       matlab.ui.control.NumericEditField
        CurrentBetEditFieldLabel  matlab.ui.control.Label
        BalanceEditField          matlab.ui.control.NumericEditField
        BalanceEditFieldLabel     matlab.ui.control.Label
        StandButton               matlab.ui.control.Button
        HitMeButton               matlab.ui.control.Button
        EStopToggleButton         matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: HitMeButton
        function HitMeButtonPushed(app, event)
%% FOCUS ON THIS, integrate the idea of using the gui to hit/ use estop
%% the game engine is fine as it is. can be sorted out later. this is more important 
%% Risk Assessment 

            % Deal a card to the player's hand
            [app.playerHand, app.BlackjackGame] = app.BlackjackGame.dealCard(app.playerHand);
            m
            % Update the player hand display
            app.updatePlayerHandDisplay();
            
            % Check if player busts
            if app.BlackjackGame.calculateTotal(app.playerHand) > 21
                uialert(app.UIFigure, 'Bust! You lose this round.', 'Game Over');
                app.BlackjackGame.balance = app.BlackjackGame.balance - app.CurrentBetEditField.Value;
                app.updateBalance();
            end
        end

%         % Button pushed function: StandButton
%         function StandButtonPushed(app, event)
%             % Dealer's turn
%             while app.BlackjackGame.calculateTotal(app.dealerHand) < 17
%                 [app.dealerHand, app.BlackjackGame] = app.BlackjackGame.dealCard(app.dealerHand);
%             end
% 
%             % Determine the result of the game
%             app.BlackjackGame.balance = app.BlackjackGame.balance + ...
%                 app.BlackjackGame.determineWinner(app.playerHand, app.dealerHand, app.CurrentBetEditField.Value);
% 
%             % Update the balance display
%             app.updateBalance();
% 
%             % Show dealer's hand
%             app.updateDealerHandDisplay();
%         end
% 
%         % Button pushed function: ResetButton
%         function ResetButtonPushed(app, event)
%             % Reset the game
%             app.BlackjackGame = BlackjackTest();  % Reset the game object
%             [app.playerHand, app.BlackjackGame] = app.BlackjackGame.dealCard([], 2);
%             [app.dealerHand, app.BlackjackGame] = app.BlackjackGame.dealCard([], 2);
% 
%             % Reset UI fields
%             app.updatePlayerHandDisplay();
%             app.updateDealerHandDisplay();
%             app.updateBalance();
%         end
%     end
% 
%     % Helper functions to update UI components
%     methods (Access = private)
% 
%         % Function to update player's hand display
%         function updatePlayerHandDisplay(app)
%             % Display player's hand and total in a UI element (e.g., Label, TextArea)
%             disp(['Player''s cards: ', app.BlackjackGame.handToString(app.playerHand)]);
%             disp(['Total: ', num2str(app.BlackjackGame.calculateTotal(app.playerHand))]);
%         end
% 
%         % Function to update dealer's hand display
%         function updateDealerHandDisplay(app)
%             % Display dealer's hand and total
%             disp(['Dealer''s cards: ', app.BlackjackGame.handToString(app.dealerHand)]);
%             disp(['Total: ', num2str(app.BlackjackGame.calculateTotal(app.dealerHand))]);
%         end
% 
%         % Function to update the balance display
%         function updateBalance(app)
%             app.BalanceEditField.Value = app.BlackjackGame.balance;
%         end
%     end
% 
%     % Component initialization
%     methods (Access = private)
% 
%         % Create UIFigure and components
%         function createComponents(app)
% 
%             % Create UIFigure and hide until all components are created
%             app.UIFigure = uifigure('Visible', 'off');
%             app.UIFigure.Position = [100 100 640 480];
%             app.UIFigure.Name = 'MATLAB App';
% 
%             % Create EStopToggleButton
%             app.EStopToggleButton = uibutton(app.UIFigure, 'state');
%             app.EStopToggleButton.Text = 'E-Stop (Toggle)';
%             app.EStopToggleButton.Position = [518 45 100 22];
% 
%             % Create HitMeButton
%             app.HitMeButton = uibutton(app.UIFigure, 'push');
%             app.HitMeButton.ButtonPushedFcn = createCallbackFcn(app, @HitMeButtonPushed, true);
%             app.HitMeButton.Position = [176 45 100 22];
%             app.HitMeButton.Text = 'Hit Me';
% 
%             % Create StandButton
%             app.StandButton = uibutton(app.UIFigure, 'push');
%             app.StandButton.ButtonPushedFcn = createCallbackFcn(app, @StandButtonPushed, true);
%             app.StandButton.Position = [53 45 100 22];
%             app.StandButton.Text = 'Stand';
% 
%             % Create BalanceEditFieldLabel
%             app.BalanceEditFieldLabel = uilabel(app.UIFigure);
%             app.BalanceEditFieldLabel.HorizontalAlignment = 'right';
%             app.BalanceEditFieldLabel.Position = [331 13 48 22];
%             app.BalanceEditFieldLabel.Text = 'Balance';
% 
%             % Create BalanceEditField
%             app.BalanceEditField = uieditfield(app.UIFigure, 'numeric');
%             app.BalanceEditField.Editable = 'off';  % Balance should not be directly edited
%             app.BalanceEditField.Position = [394 13 100 22];
% 
%             % Create CurrentBetEditFieldLabel
%             app.CurrentBetEditFieldLabel = uilabel(app.UIFigure);
%             app.CurrentBetEditFieldLabel.HorizontalAlignment = 'right';
%             app.CurrentBetEditFieldLabel.Position = [313 45 66 22];
%             app.CurrentBetEditFieldLabel.Text = 'Current Bet';
% 
%             % Create CurrentBetEditField
%             app.CurrentBetEditField = uieditfield(app.UIFigure, 'numeric');
%             app.CurrentBetEditField.Position = [394 45 100 22];
% 
%             % Create ResetButton
%             app.ResetButton = uibutton(app.UIFigure, 'push');
%             app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
%             app.ResetButton.Position = [518 13 100 22];
%             app.ResetButton.Text = 'Reset';
% 
%             % Show the figure after all components are created
%             app.UIFigure.Visible = 'on';
%         end
%     end
% 
%     % App creation and deletion
%     methods (Access = public)
% 
%         % Construct app
%         function app = app1
%             % Initialize the Blackjack game
%             app.BlackjackGame = BlackjackTest();
% 
%             % Deal initial cards
%             [app.playerHand, app.BlackjackGame] = app.BlackjackGame.dealCard([], 2);
%             [app.dealerHand, app.BlackjackGame] = app.BlackjackGame.dealCard([], 2);
% 
%             % Create UIFigure and components
%             createComponents(app)
% 
%             % Register the app with App Designer
%             registerApp(app, app.UIFigure)
% 
%             % Initialize the UI with game data
%             app.updatePlayerHandDisplay();
%             app.updateDealerHandDisplay();
%             app.updateBalance();
% 
%             if nargout == 0
%                 clear app
%             end
%         end
% 
%         % Code that executes before app deletion
%         function delete(app)
%             % Delete UIFigure when app is deleted
%             delete(app.UIFigure)
%         end
%     end
% end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EStopToggleButton = uibutton(app.UIFigure, 'state');
            app.EStopToggleButton.Text = 'E-Stop (Toggle)';
            app.HitMeButton = uibutton(app.UIFigure, 'push');
            app.HitMeButton.ButtonPushedFcn = createCallbackFcn(app, @HitMeButtonPushed, true);
            app.HitMeButton.Text = 'Hit Me';
            app.StandButton = uibutton(app.UIFigure, 'push');
            app.StandButton.Text = 'Stand';
            app.BalanceEditFieldLabel = uilabel(app.UIFigure);
            app.BalanceEditFieldLabel.Text = 'Balance';
            app.BalanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.CurrentBetEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentBetEditFieldLabel.Text = 'Current Bet';
            app.CurrentBetEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end